classdef SNS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        FSNPanel                matlab.ui.container.Panel
        RhythmGeneratorButton   matlab.ui.control.Button
        PatternformationButton  matlab.ui.control.Button
        MotoneuronButton        matlab.ui.control.Button
        TestButton              matlab.ui.control.Button
        SimulationPanel         matlab.ui.container.Panel
        TimeEditFieldLabel      matlab.ui.control.Label
        Time                    matlab.ui.control.NumericEditField
        dtEditFieldLabel        matlab.ui.control.Label
        TimeStep                matlab.ui.control.NumericEditField
        RunButton               matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RhythmGeneratorButton
        function RhythmGeneratorButtonPushed(app, event)
            % Position
            z=app.UIAxes.CurrentPoint;
            x=z(1,1);  y=z(1,2);
            w = 0.6;
            h = 0.4;
            d = 1;
            
            % Neurons
            rectangle(app.UIAxes,'Position',[x y w h],'FaceColor','r','Curvature',1);% RG E
            rectangle(app.UIAxes,'Position',[x+1.5*d y w h],'FaceColor','b','Curvature',1);% RG F
            rectangle(app.UIAxes,'Position',[x y+d w h],'FaceColor','c','Curvature',1);% RG E IN
            rectangle(app.UIAxes,'Position',[x+1.5*d y+d w h],'FaceColor','c','Curvature',1);% RG F IN
            
            % Synapses
            line (app.UIAxes,[x+w/2 x+w/2],[y+h y+d],'color','k');% RG E -> RG E IN
            line (app.UIAxes,[x+1.5*d+w/2 x+1.5*d+w/2],[y+h y+d],'color','k');% RG F -> RG F IN
            line (app.UIAxes,[x+w x+1.5*d+w/5],[y+d+h/4 y+h],'color','k');% RG E IN -> RG F
            line (app.UIAxes,[x+1.5*d x+4/5*w],[y+d+h/4 y+h],'color','k');% RG F IN -> RG E
            
            line (app.UIAxes,[x+w x+0.75*d+w/2],[y+2/3*h y+h],'LineStyle',':','color','k');
            line (app.UIAxes,[x+0.75*d+w/2 x+1.5*d],[y+h y+2/3*h],'LineStyle',':','color','k');
            
            line (app.UIAxes,[x+w x+0.75*d+w/2],[y+h/3 y],'LineStyle',':','color','k');
            line (app.UIAxes,[x+0.75*d+w/2 x+1.5*d],[y y+h/3],'LineStyle',':','color','k');
            
            
            % Synapse symbol
            %             plot(app.UIAxes,x+1.5*d+w/5,y+h,'.','color','k','MarkerSize',20);
            %             plot(app.UIAxes,x+4/5*w,y+h,'.','color','k','MarkerSize',20);
            %             plot(app.UIAxes,x+w/2,y+d,'*','color','k','MarkerSize',6);
            %             plot(app.UIAxes,x+1.5*d,y+2/3*h,'*','color','k','MarkerSize',6);
            %             plot(app.UIAxes,x+w,y+h/3,'*','color','k','MarkerSize',6);
            
            
        end

        % Button pushed function: PatternformationButton
        function PatternformationButtonPushed(app, event)
            % Position
            z=app.UIAxes.CurrentPoint;
            x=z(1,1);  y=z(1,2);
            w = 0.6;
            h = 0.4;
            d = 1;
            
            % Neurons
            rectangle(app.UIAxes,'Position',[x y w h],'FaceColor','r','Curvature',1);% PF E
            rectangle(app.UIAxes,'Position',[x+1.5*d y w h],'FaceColor','b','Curvature',1);% PF F
            rectangle(app.UIAxes,'Position',[x y+d w h],'FaceColor','c','Curvature',1);% PF E IN
            rectangle(app.UIAxes,'Position',[x+1.5*d y+d w h],'FaceColor','c','Curvature',1);% PF F IN
            
            
            % Synapses
            line (app.UIAxes,[x+w/2 x+w/2],[y+h y+d],'color','k');% PF E -> PF E IN
            line (app.UIAxes,[x+1.5*d+w/2 x+1.5*d+w/2],[y+h y+d],'color','k'); % PF F -> PF F IN
            line (app.UIAxes,[x+w x+1.5*d+w/5],[y+d+h/4 y+h],'color','k');% PF E IN -> PF F
            line (app.UIAxes,[x+1.5*d x+4/5*w],[y+d+h/4 y+h],'color','k');% PF F IN -> PF E
            
            
            %             % Synapse symbol
            %             plot(x+1.5*d+w/5,y+h,'.','color','k','MarkerSize',20);
            %             plot(x+4/5*w,y+h,'.','color','k','MarkerSize',20);
            %             plot(x+w/2,y+d,'*','color','k','MarkerSize',6);
            %             plot(x+1.5*d+w/2,y+d,'*','color','k','MarkerSize',6);
            %             keyboard
        end

        % Button pushed function: MotoneuronButton
        function MotoneuronButtonPushed(app, event)
            % Position
            z=app.UIAxes.CurrentPoint;
            x=z(1,1);  y=z(1,2);
            w = 0.6;
            h = 0.4;
            d = 1;
            
            % Neurons
            rectangle(app.UIAxes,'Position',[x y w h],'FaceColor','r','Curvature',1);% PF E
            rectangle(app.UIAxes,'Position',[x+1.5*d y w h],'FaceColor','b','Curvature',1);% PF F
            rectangle(app.UIAxes,'Position',[x y-d w h],'FaceColor','c','Curvature',1);% Ia E IN
            rectangle(app.UIAxes,'Position',[x+1.5*d y-d w h],'FaceColor','c','Curvature',1);% Ia E IN
            rectangle(app.UIAxes,'Position',[x y-2*d w h],'FaceColor','y','Curvature',1);% MN E
            rectangle(app.UIAxes,'Position',[x+1.5*d y-2*d w h],'FaceColor','y','Curvature',1);% MN F
            rectangle(app.UIAxes,'Position',[x y-3*d w h],'FaceColor','c','Curvature',1);% RE E
            rectangle(app.UIAxes,'Position',[x+1.5*d y-3*d w h],'FaceColor','c','Curvature',1);% RE F
            
            % Synapses
            line (app.UIAxes,[x+w/2 x+w/2],[y y-d+h],'color','k');% PF E -> Ia E IN
            line (app.UIAxes,[x+1.5*d+w/2 x+1.5*d+w/2],[y y-d+h],'color','k');% PF E -> Ia F IN
            line (app.UIAxes,[x+w x+1.5*d],[y-d+h/2 y-d+h/2],'color','k');% Ia E IN ->Ia F IN
            line (app.UIAxes,[x+w x+0.75*d+w/2],[y-d+2/3*h y-d+h],'color','k');
            line (app.UIAxes,[x+0.75*d+w/2 x+1.5*d],[y-d+h y-d+2/3*h],'color','k');% Ia F IN ->Ia E IN
            line (app.UIAxes,[x+w x+1.5*d],[y-d+h/3 y-2*d+3/4*h],'color','k');% Ia E IN ->MN F
            line (app.UIAxes,[x+1.5*d x+w],[y-d+h/3 y-2*d+3/4*h],'color','k');% Ia F IN ->MN E
            line (app.UIAxes,[x+w/2 x+w/2],[y-2*d y-3*d+h],'color','k');% MN E -> RE E
            line (app.UIAxes,[x+1.5*d+w/2 x+1.5*d+w/2],[y-2*d y-3*d+h],'color','k');% MN E -> RE E
            line (app.UIAxes,[x+w x+0.75*d+w/2],[y-3*d+2/3*h y-3*d+h],'color','k');
            line (app.UIAxes,[x+0.75*d+w/2 x+1.5*d],[y-3*d+h y-3*d+2/3*h],'color','k');% RE E ->RE F
            line (app.UIAxes,[x+w x+0.75*d+w/2],[y-3*d+h/3 y-3*d],'color','k');
            line (app.UIAxes,[x+0.75*d+w/2 x+1.5*d],[y-3*d y-3*d+h/3],'color','k');% RE F ->RE E
            
            line (app.UIAxes,[x x-d/2],[y-3*d+2/3*h y-2*d+h/2],'color','k');
            line (app.UIAxes,[x-d/2 x],[y-2*d+h/2 y-d+h/3],'color','k');% RE E ->Ia E IN
            
            line (app.UIAxes,[x+1.5*d+w x+2*d+w],[y-3*d+2/3*h y-2*d+h/2],'color','k');
            line (app.UIAxes,[x+2*d+w x+1.5*d+w],[y-2*d+h/2 y-d+h/3],'color','k');% RE F ->Ia F IN
            
            line (app.UIAxes,[x+w x+1.5*w],[y+h/2 y+h/2],'color','k');% PF E -> MN E
            line (app.UIAxes,[x+1.5*w x+1.5*w],[y+h/2  y-2*d+h/2],'color','k');
            line (app.UIAxes,[x+w x+1.5*w],[y-2*d+h/2 y-2*d+h/2],'color','k');
            
            line (app.UIAxes,[x+1.5*d x+1.5*d-w/2],[y+h/2 y+h/2],'color','k');% PF F -> MN F
            line (app.UIAxes,[x+1.5*d-w/2 x+1.5*d-w/2],[y+h/2  y-2*d+h/2],'color','k');
            line (app.UIAxes,[x+1.5*d x+1.5*d-w/2],[y-2*d+h/2 y-2*d+h/2],'color','k');
            
            %             % Synapse symbol
            %             plot(x+w/2,y-d+h,'*','color','k','MarkerSize',6);
            %             plot(x+1.5*d+w/2,y-d+h,'*','color','k','MarkerSize',6);
            %             plot(x+w,y-2*d+h/2,'*','color','k','MarkerSize',6);
            %             plot(x+1.5*d,y-2*d+h/2,'*','color','k','MarkerSize',6);
            %             plot(x+w/2,y-3*d+h,'*','color','k','MarkerSize',6);
            %             plot(x+1.5*d+w/2,y-3*d+h,'*','color','k','MarkerSize',6);
            %             plot(x+1.5*d,y-d+h/2,'.','color','k','MarkerSize',15);
            %             plot(x+w,y-d+2/3*h,'.','color','k','MarkerSize',15);
            %             plot(x+1.5*d,y-2*d+3/4*h,'.','color','k','MarkerSize',15);
            %             plot(x+w,y-2*d+3/4*h,'.','color','k','MarkerSize',15);
            %             plot(x+1.5*d,y-3*d+2/3*h,'.','color','k','MarkerSize',15);
            %             plot(x,y-d+h/3,'.','color','k','MarkerSize',15);
            %             plot(x+1.5*d+w,y-d+h/3,'.','color','k','MarkerSize',15);
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            
            % Common neuron properties
            C = 5; %nF
            R = 1; %M ohm
            Gm = 5; %uS
            Eca = 200; %mV
            Vr = -60; %mV
            
            %Calcium Channel
            % m, the activation variable, is rapid, so m=m_(inf)
            % h, the deactivation variable, is slow,
            Tm = 2; %ms
            Sm = .1; % <=================================
            VmidM = -40; %mV
            Th = 1000; %ms
            Sh = -.1; % <=================================
            VmidH = -100; %mV
            
            % Commmon Synapse properties
            G = 2; %uS
            V_th_low = -60; %mV
            V_th_high = -40; %mV
            
            % Position
            z=app.UIAxes.CurrentPoint;
            x=z(1,1);  y=z(1,2);
            w = 0.6;
            h = 0.4;
            d = 1;
            
            % Neurons
            rectangle(app.UIAxes,'Position',[x y w h],'FaceColor','r','Curvature',1);% EXT
            app.UIAxes.Children(1).Tag = 'EXT';
            app.UIAxes.Children(1).UserData = [C;R;Gm;Eca;Vr;Tm;Sm;VmidM;Th;Sh;VmidH;0;4]';
            
            rectangle(app.UIAxes,'Position',[x+1.5*d y w h],'FaceColor','b','Curvature',1);% FLX
            app.UIAxes.Children(1).Tag = 'FLX';
            app.UIAxes.Children(1).UserData = [C;R;Gm;Eca;Vr;Tm;Sm;VmidM;Th;Sh;VmidH;0;4]';
            
            rectangle(app.UIAxes,'Position',[x y+d w h],'FaceColor','c','Curvature',1);% EXT IN
            app.UIAxes.Children(1).Tag = 'EXT IN';
            app.UIAxes.Children(1).UserData = [C;R;0;Eca;Vr;Tm;Sm;VmidM;Th;Sh;VmidH;0;0]';
            
            rectangle(app.UIAxes,'Position',[x+1.5*d y+d w h],'FaceColor','c','Curvature',1);% PF F IN
            app.UIAxes.Children(1).Tag = 'FLX IN';
            app.UIAxes.Children(1).UserData = [C;R;0;Eca;Vr;Tm;Sm;VmidM;Th;Sh;VmidH;0;0]';
            
            % Synapses
            line (app.UIAxes,[x+w/2 x+w/2],[y+h y+d],'color','k');% Ext->Ext IN
            app.UIAxes.Children(1).Tag = 'Ext->Ext IN';
            app.UIAxes.Children(1).UserData = [G;-40;V_th_low;V_th_high];
            app.UIAxes.Children(1).Marker = 'v';
            app.UIAxes.Children(1).MarkerIndices = 2;
            app.UIAxes.Children(1).MarkerFaceColor = 'w';
            
            line (app.UIAxes,[x+1.5*d+w/2 x+1.5*d+w/2],[y+h y+d],'color','k'); % FLX->FLX IN
            app.UIAxes.Children(1).Tag = 'Flx->Flx IN';
            app.UIAxes.Children(1).UserData = [G;-40;V_th_low;V_th_high];
            app.UIAxes.Children(1).Marker = 'v';
            app.UIAxes.Children(1).MarkerIndices = 2;
            app.UIAxes.Children(1).MarkerFaceColor = 'w';
            
            line (app.UIAxes,[x+w x+1.5*d+w/5],[y+d+h/4 y+h],'color','k');% EXT IN -> FLX
            app.UIAxes.Children(1).Tag = 'EXT IN -> FLX';
            app.UIAxes.Children(1).UserData = [G;-70;V_th_low;V_th_high];
            app.UIAxes.Children(1).Marker = 'o';
            app.UIAxes.Children(1).MarkerIndices = 2;
            app.UIAxes.Children(1).MarkerFaceColor = 'k';
            
            line (app.UIAxes,[x+1.5*d x+4/5*w],[y+d+h/4 y+h],'color','k');% FLX IN -> EXT
            app.UIAxes.Children(1).Tag = 'FLX IN -> EXT';
            app.UIAxes.Children(1).UserData = [G;-70;V_th_low;V_th_high];
            app.UIAxes.Children(1).Marker = 'o';
            app.UIAxes.Children(1).MarkerIndices = 2;
            app.UIAxes.Children(1).MarkerFaceColor = 'k';
            
            %             keyboard
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            % Initial Values
            time = app.Time.Value;
            dt = app.TimeStep.Value;
            V_r = -60;
            ext_stim = zeros(time/dt,4);
            
            % Four neuron nodes represent EXT;FLX;EXT IN and FLX IN
            nprops = zeros(4,13,round(time/dt));
            
            %Put these values into the proper form:(neuron,property index, time step).
            %Each row of nprops outlines the properties of another neuron
            nprops(1,:,1) = app.UIAxes.Children(8).UserData;
            nprops(2,:,1) = app.UIAxes.Children(7).UserData;
            nprops(3,:,1) = app.UIAxes.Children(6).UserData;
            nprops(4,:,1) = app.UIAxes.Children(5).UserData;
            
            %for neuron state we have (property index, time step, neuron)
            nstate = zeros(6,time/dt,4);
            
            %assign the initial condition
            n_init(:,1,1) = [V_r+1;0;0;0;0;0]; %V(t),m,h,time,I_ext,h_inf
            n_init(:,1,2) = [V_r;0;0;0;0;0];
            n_init(:,1,3) = [V_r;0;0;0;0;0]; %V(t),m,h,time,I_ext
            n_init(:,1,4) = [V_r;0;0;0;0;0];
            
            nstate(:,1,:) = n_init;
            
            %%% Synapse population set up
            sprops = zeros(4,4,round(time/dt));
            
            %Each row of sprops outlines the properties of another neuron
            sprops(1,:,1) = app.UIAxes.Children(4).UserData;
            sprops(2,:,1) = app.UIAxes.Children(3).UserData;
            sprops(3,:,1) = app.UIAxes.Children(2).UserData;
            sprops(4,:,1) = app.UIAxes.Children(1).UserData;
            
            %for synapse state we have (property index, time step,neuron)
            sstate = zeros(3,time/dt,4);
            
            %sstate is filled like nstate: (neuron,property index, time step)
            s_init(:,1,1) = [0;0;0];%V_pre, V_post, Current %1 -> 3
            s_init(:,1,2) = [0;0;0];%V_pre, V_post, Current %2 -> 4
            s_init(:,1,3) = [0;0;0];%V_pre, V_post, Current %3 -> 2
            s_init(:,1,4) = [0;0;0];%V_pre, V_post, Current %4 -> 1
            
            
            sstate(:,1,:) = s_init;
            conn_map = [1 3;2 4;3 2;4 1];
            
            nstate = simulate(nstate,nprops,sstate,sprops,conn_map,ext_stim,time,dt); % simulates CPG with no input
            
            Plot_test(nstate);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'SNS Architecture')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XLim = [0 10];
            app.UIAxes.YLim = [0 10];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.FSNPanel = uipanel(app.UIFigure);
            app.FSNPanel.Title = 'FSN';
            app.RhythmGeneratorButton = uibutton(app.FSNPanel, 'push');
            app.RhythmGeneratorButton.ButtonPushedFcn = createCallbackFcn(app, @RhythmGeneratorButtonPushed, true);
            app.RhythmGeneratorButton.Text = {'Rhythm Generator'; ''};
            app.PatternformationButton = uibutton(app.FSNPanel, 'push');
            app.PatternformationButton.ButtonPushedFcn = createCallbackFcn(app, @PatternformationButtonPushed, true);
            app.PatternformationButton.Text = {'Pattern formation'; ''};
            app.MotoneuronButton = uibutton(app.FSNPanel, 'push');
            app.MotoneuronButton.ButtonPushedFcn = createCallbackFcn(app, @MotoneuronButtonPushed, true);
            app.MotoneuronButton.Text = {'Motoneuron'; ''};
            app.TestButton = uibutton(app.FSNPanel, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.SimulationPanel = uipanel(app.UIFigure);
            app.SimulationPanel.Title = 'Simulation';
            app.TimeEditFieldLabel = uilabel(app.SimulationPanel);
            app.TimeEditFieldLabel.Text = 'Time';
            app.Time = uieditfield(app.SimulationPanel, 'numeric');
            app.Time.Limits = [0 Inf];
            app.Time.Value = 5000;
            app.dtEditFieldLabel = uilabel(app.SimulationPanel);
            app.dtEditFieldLabel.Text = 'dt';
            app.TimeStep = uieditfield(app.SimulationPanel, 'numeric');
            app.TimeStep.Limits = [0 Inf];
            app.TimeStep.Value = 1;
            app.RunButton = uibutton(app.SimulationPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = {'Run'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SNS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end